classdef TFMRegistrationGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ImagePreview                   matlab.ui.control.UIAxes
        ContinueButton                 matlab.ui.control.Button
        SelectImageButton              matlab.ui.control.Button
        TimeSliderLabel                matlab.ui.control.Label
        TimeSlider                     matlab.ui.control.Slider
        ZSliderLabel                   matlab.ui.control.Label
        ZSlider                        matlab.ui.control.Slider
        ThresholdSliderLabel           matlab.ui.control.Label
        ThresholdSlider                matlab.ui.control.Slider
        BWimage                        matlab.ui.control.UIAxes
        ComputeStatsButton             matlab.ui.control.Button
        RotateImagesButton             matlab.ui.control.Button
        AreaMinEditFieldLabel          matlab.ui.control.Label
        AreaMinEditField               matlab.ui.control.NumericEditField
        SoliditiyMinEditFieldLabel     matlab.ui.control.Label
        SoliditiyMinEditField          matlab.ui.control.NumericEditField
        RotateSliderLabel              matlab.ui.control.Label
        RotateSlider                   matlab.ui.control.Slider
        RotationDegreesEditFieldLabel  matlab.ui.control.Label
        RotationDegreesEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
       
        fullPath % Full path to file
        stack_info % Information on Image Stack
        threshold % Threshold for image detection
        images % Cell containing images for faster display
        curT % Current Time
        curZ % Current Slice
        stats % Object Statistics
        areaFilt % Area filter [min, max]
        solidityFilt % Solidity filter [min, max]
        rotationOri % Mean rotation orientation in degrees
        rotationMatrix % Rotaional Matrix for 3D space
        centerDisplacement % XY center for average object
        rotValue % The rotate value applied to image
    end
    
    properties (Access = public)
        closenum % When equals 1, close the GUI
    end
    
    methods (Access = private)
        
        function [] = plotImage(app)
            
            
            imshow(imrotate(app.images{app.curZ},-mean(app.rotationOri)),'Parent',app.ImagePreview)
            
                
            %Plot cell if present
%             if app.plotCell == 1
%                 hold(app.ImagePreview, "on")      
%                 imshow(app.cellImage{app.curTime},'Parent',app.ImagePreview)
%             end

%             quiver(app.ImagePreview,x + app.trans(1),y + app.trans(2),dx,dy,app.scale)
            
            hold(app.ImagePreview, "off")
        end
        
        
        function [] = ObjectDetection(app)
            
            for i = 1:length(app.stack_info)
               
               img_temp = app.images{i};
               img_temp_bin = imbinarize(img_temp,'adaptive','ForegroundPolarity', ...
                   'bright','Sensitivity',app.threshold);
               BW2 = bwpropfilt(img_temp_bin,'Area',app.areaFilt,6);
               BW2 = bwpropfilt(BW2,'Solidity',app.solidityFilt);
               app.stats = [app.stats ; regionprops(BW2,'area','Centroid', ...
                   'Orientation','Solidity')];
            end
            
        end
        
        function [] = plotBW(app)
            
            img_temp = app.images{app.curZ};
            img_temp_bin = imbinarize(img_temp,'adaptive','ForegroundPolarity', ...
                   'bright','Sensitivity',app.threshold);
            BW2 = bwpropfilt(img_temp_bin,'Area',app.areaFilt,4);
            BW2 = bwpropfilt(BW2,'Solidity',app.solidityFilt);
            BW2 = imrotate(BW2, -mean(app.rotationOri));
            imshow(BW2,'Parent',app.BWimage)
            
            hold(app.BWimage, "off")
        end
        
        function [] = rotateImg(app)
            
            x_size = app.stack_info(1).Width;
            y_size = app.stack_info(1).Height;
            
            ori = arrayfun(@(x) x.Orientation, app.stats);
            cent_cord = arrayfun(@(x) x.Centroid, app.stats, 'UniformOutput', false);

            [ori,TF] = rmoutliers(ori);
            cent_cord = cent_cord(~TF);
            cent_y_dist = zeros(size(ori));
            
            axang = mean(ori)*pi/180*[0 0 1];
            rot_mat = rotationVectorToMatrix(axang);
            
            
            for i = 1:length(ori)
                cent_y_dist(i) = (cent_cord{i}(2)-y_size/2)*cosd(ori(i)) + ...
                    (cent_cord{i}(1)-x_size/2)*sind(ori(i));
            end
                
            disp_vec = [0 mean(cent_y_dist)] / rot_mat(1:2,1:2) + ...
                [x_size/2 y_size/2];
            
            app.rotationOri = mean(ori);
            app.rotValue = mean(app.rotationOri);
            app.RotateSlider.Value = app.rotValue;
            app.RotationDegreesEditField.Value = app.rotValue;
            app.rotationMatrix = rot_mat;
            app.centerDisplacement = disp_vec;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.fullPath = 0;
            app.stack_info = 0;
            app.images = {};
            app.curT = 1;
            app.curZ = 1;
            app.threshold = 0.6;
            app.areaFilt = [5E4, 1E9];
            app.solidityFilt = [0.45, 1];
            app.rotationOri = 0;
            app.rotationMatrix = 0;
            app.centerDisplacement = [0,0];
            app.closenum = 0;
            app.rotValue = 0;
            %If only one time is present, make time slider invisible
%             if numel(tractions) == 1
%                app.TimeSlider.Visible = "off"; 
%                app.TimeSliderLabel.Visible = "off";
%             else
%                 app.TimeSlider.Limits = [1, numel(tractions)];
%             end
%             
%             app.TimeSlider.Value = 1;
%             app.trans = trans;
%             app.scale = 1;
%             app.ScaleEditField.Value = 1;
      
            
            %3D plot first time point with Forces
%             plotVectors(app)
            %quiver(app.ImagePreview,app.mesh(:,1),app.mesh(:,2),app.xdf,app.ydf)

        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            value = app.TimeSlider.Value;
            %app.curTime = value;
            %adjustTime(app)
            %Update the plots based on time
            %plotVectors(app)
        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            %Get channel files from folder and load them
            [file, path] = uigetfile( {'*.*'}, 'Select a File');
            
            app.fullPath = fullfile(path,file);
            app.stack_info = imfinfo(app.fullPath);
            for i = 1:length(app.stack_info)
               app.images{i} = imread(app.fullPath, i);

            end
            
            app.ZSlider.Limits = [1, length(app.stack_info)];
            
            disp(['Loading File: ' app.fullPath])
            
            plotImage(app)
            plotBW(app)

        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            app.closenum = 1;
        end

        % Value changing function: ThresholdSlider
        function ThresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            
            app.threshold = changingValue;
            
            %Replot
            plotImage(app)
            plotBW(app)
        end

        % Value changing function: ZSlider
        function ZSliderValueChanging(app, event)
            changingValue = event.Value;
            
            app.curZ = round(changingValue);
            
            plotImage(app)
            plotBW(app)
        end

        % Button pushed function: ComputeStatsButton
        function ComputeStatsButtonPushed(app, event)
            ObjectDetection(app)
            disp('Complete')
        end

        % Button pushed function: RotateImagesButton
        function RotateImagesButtonPushed(app, event)
            rotateImg(app)
            plotImage(app)
            plotBW(app)
        end

        % Value changed function: AreaMinEditField
        function AreaMinEditFieldValueChanged(app, event)
            value = app.AreaMinEditField.Value;
            
            app.areaFilt = [value, 1E9];
            
            plotImage(app)
            plotBW(app)
        end

        % Value changed function: SoliditiyMinEditField
        function SoliditiyMinEditFieldValueChanged(app, event)
            value = app.SoliditiyMinEditField.Value;
            
            app.solidityFilt = [value, 1];
            
            plotImage(app)
            plotBW(app)
        end

        % Value changing function: RotateSlider
        function RotateSliderValueChanging(app, event)
            changingValue = event.Value;
            app.rotValue = changingValue;
            app.RotationDegreesEditField.Value = changingValue;
            
            plotImage(app)
            plotBW(app)
        end

        % Value changed function: RotationDegreesEditField
        function RotationDegreesEditFieldValueChanged(app, event)
            value = app.RotationDegreesEditField.Value;
            app.rotValue = value;
            app.RotateSlider.Value = value;
            
            plotImage(app)
            plotBW(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImagePreview = uiaxes(app.UIFigure);
            title(app.ImagePreview, 'Image Preview')
            xlabel(app.ImagePreview, 'X')
            ylabel(app.ImagePreview, 'Y')
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.SelectImageButton = uibutton(app.UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.TimeSliderLabel = uilabel(app.UIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.UIFigure);
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.ZSliderLabel = uilabel(app.UIFigure);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.UIFigure);
            app.ZSlider.ValueChangingFcn = createCallbackFcn(app, @ZSliderValueChanging, true);
            app.ThresholdSliderLabel = uilabel(app.UIFigure);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.UIFigure);
            app.ThresholdSlider.Limits = [0 1];
            app.ThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdSliderValueChanging, true);
            app.ThresholdSlider.Value = 0.6;
            app.BWimage = uiaxes(app.UIFigure);
            title(app.BWimage, 'Binarized Image')
            xlabel(app.BWimage, 'X')
            ylabel(app.BWimage, 'Y')
            app.ComputeStatsButton = uibutton(app.UIFigure, 'push');
            app.ComputeStatsButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeStatsButtonPushed, true);
            app.ComputeStatsButton.Text = 'Compute Stats';
            app.RotateImagesButton = uibutton(app.UIFigure, 'push');
            app.RotateImagesButton.ButtonPushedFcn = createCallbackFcn(app, @RotateImagesButtonPushed, true);
            app.RotateImagesButton.Text = 'Rotate Images';
            app.AreaMinEditFieldLabel = uilabel(app.UIFigure);
            app.AreaMinEditFieldLabel.Text = 'Area Min';
            app.AreaMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.AreaMinEditField.ValueChangedFcn = createCallbackFcn(app, @AreaMinEditFieldValueChanged, true);
            app.SoliditiyMinEditFieldLabel = uilabel(app.UIFigure);
            app.SoliditiyMinEditFieldLabel.Text = 'Soliditiy Min';
            app.SoliditiyMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.SoliditiyMinEditField.ValueChangedFcn = createCallbackFcn(app, @SoliditiyMinEditFieldValueChanged, true);
            app.RotateSliderLabel = uilabel(app.UIFigure);
            app.RotateSliderLabel.Text = 'Rotate';
            app.RotateSlider = uislider(app.UIFigure);
            app.RotateSlider.Limits = [0 180];
            app.RotateSlider.ValueChangingFcn = createCallbackFcn(app, @RotateSliderValueChanging, true);
            app.RotationDegreesEditFieldLabel = uilabel(app.UIFigure);
            app.RotationDegreesEditFieldLabel.Text = 'Rotation (Degrees)';
            app.RotationDegreesEditField = uieditfield(app.UIFigure, 'numeric');
            app.RotationDegreesEditField.Limits = [0 180];
            app.RotationDegreesEditField.ValueChangedFcn = createCallbackFcn(app, @RotationDegreesEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TFMRegistrationGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end