classdef TPTGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TrackedLabel              matlab.ui.control.Label
        UpdateButton              matlab.ui.control.Button
        outlrThresEditField       matlab.ui.control.NumericEditField
        outlrThresEditFieldLabel  matlab.ui.control.Label
        nSpheresEditField         matlab.ui.control.NumericEditField
        nSpheresEditFieldLabel    matlab.ui.control.Label
        maxIterEditField          matlab.ui.control.NumericEditField
        maxIterEditFieldLabel     matlab.ui.control.Label
        ContinueButton            matlab.ui.control.Button
        fmThresEditField          matlab.ui.control.NumericEditField
        fmThresEditFieldLabel     matlab.ui.control.Label
        knnFMEditField            matlab.ui.control.NumericEditField
        knnFMEditFieldLabel       matlab.ui.control.Label
        knnFDEditField            matlab.ui.control.NumericEditField
        knnFDEditFieldLabel       matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %Parse tpttParameter
            knnFD % # of NN in feature descriptor
            knnFM % # of NN in neighborhood similarity comparison
            fmThres % Threshold for minimum # of common NN in similiarity comparison
            maxIter % Maximum number of iterations in pspt
            nSpheres  % Number of spheres in feature descriptor
            outlrThres % Residual threshold in outlier removal
            sizeI % Size of image
            x % Particle Position matrix (cell array with nx3 array of positions at each time point)
            displacements % Particle Displacements
            curMatches % Particle Locations
    end
    
    properties (Access = public)
            tptParamFinal % Final TPT parameters to use
        closenum % Description
    end
    
    methods (Access = private)
        
        function runTPT(app)
            %rerun TPT and update the plotting based on latest values
            tptParameter.knnFD = app.knnFD;
            tptParameter.knnFM = app.knnFM;
            tptParameter.fmThres = app.fmThres;
            tptParameter.maxIter = app.maxIter;
            tptParameter.nSpheres = app.nSpheres;
            tptParameter.outlrThres = app.outlrThres;
            tptParameter.sizeI = app.sizeI;
            
            predictor.flag = false;
            predictor.x0 = [];
            predictor.u = [];
            
            track= TPT(app.x{1}{1},app.x{2}{1},tptParameter,predictor);
            
            initLocations = app.x{1}{1};
            finLocations = app.x{2}{1};
            ind = find(track ~= 0);
            map = track(ind);
            app.curMatches = [initLocations(ind,:) finLocations(map,:)];
            app.displacements = app.curMatches(:,1:3) - app.curMatches(:,4:6);
            nBeads = size(app.x{1}{1},1);
            nTracked= sum(track>0)/nBeads;    %Fraction of beads tracked
            app.TrackedLabel.Text = ['Tracked ', num2str(nTracked*100),' %'];
            plotTracks(app)
            
        end
        
        function plotTracks(app)
            %Plot tracks with current displacement values
            ax1 = app.UIAxes;
                       
            scatter(ax1,app.x{1}{1}(:,1)+1,app.x{1}{1}(:,2)+1,'r')
            hold(ax1,"on")
            scatter(ax1,app.x{2}{1}(:,1)+1,app.x{2}{1}(:,2)+1,'g')
            axis(ax1,'equal')
            quiver(ax1, app.curMatches(:,4)+1,app.curMatches(:,5)+1,app.displacements(:,1),...
                app.displacements(:,2),0)
            hold(ax1,"off")
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, x, tptParameter)
            %Parse tpttParameter
                app.knnFD = tptParameter.knnFD;    % # of NN in feature descriptor
                app.knnFM = tptParameter.knnFM;    % # of NN in neighborhood similarity comparison
                app.fmThres = tptParameter.fmThres;    % Threshold for minimum # of common NN in similiarity comparison
                app.maxIter = tptParameter.maxIter;    % Maximum number of iterations in pspt
                app.nSpheres = tptParameter.nSpheres;  % Number of spheres in feature descriptor
                app.outlrThres = tptParameter.outlrThres;  % Residual threshold in outlier removal
                app.sizeI = tptParameter.sizeI;    % Size of image
                app.x = x;
                app.closenum = 0;
                
                %Set initial values in GUI
                app.knnFDEditField.Value = app.knnFD;
                app.knnFMEditField.Value = app.knnFM;
                app.fmThresEditField.Value = app.fmThres;
                app.maxIterEditField.Value = app.maxIter;
                app.nSpheresEditField.Value = app.nSpheres;
                app.outlrThresEditField.Value = app.outlrThres;
                
                runTPT(app)
                plotTracks(app)

        end

        % Value changed function: knnFDEditField
        function knnFDEditFieldValueChanged(app, event)
            value = app.knnFDEditField.Value;
            app.knnFD = value;
        end

        % Value changed function: knnFMEditField
        function knnFMEditFieldValueChanged(app, event)
            value = app.knnFMEditField.Value;
            app.knnFM = value;
        end

        % Value changed function: fmThresEditField
        function fmThresEditFieldValueChanged(app, event)
            value = app.fmThresEditField.Value;
            app.fmThres = value;
        end

        % Value changed function: maxIterEditField
        function maxIterEditFieldValueChanged(app, event)
            value = app.maxIterEditField.Value;
            app.maxIter = value;
        end

        % Value changed function: nSpheresEditField
        function nSpheresEditFieldValueChanged(app, event)
            value = app.nSpheresEditField.Value;
            app.nSpheres = value;
        end

        % Value changed function: outlrThresEditField
        function outlrThresEditFieldValueChanged(app, event)
            value = app.outlrThresEditField.Value;
            app.outlrThres = value;
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            runTPT(app)
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            tptParameter.knnFD = app.knnFD;
            tptParameter.knnFM = app.knnFM;
            tptParameter.fmThres = app.fmThres;
            tptParameter.maxIter = app.maxIter;
            tptParameter.nSpheres = app.nSpheres;
            tptParameter.outlrThres = app.outlrThres;
            tptParameter.sizeI = app.sizeI;
            %Return tptParameter to the same formatting as funTPT
            emptyCell = cell(1);           
            emptyCell{1} = tptParameter;
            tptParameter = emptyCell;
            app.tptParamFinal = tptParameter;
            %assignin('base',"newtptParameter",tptParameter)
            %closereq
            app.closenum = 1;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.knnFDEditFieldLabel = uilabel(app.UIFigure);
            app.knnFDEditFieldLabel.Text = 'knnFD';
            app.knnFDEditField = uieditfield(app.UIFigure, 'numeric');
            app.knnFDEditField.ValueChangedFcn = createCallbackFcn(app, @knnFDEditFieldValueChanged, true);
            app.knnFMEditFieldLabel = uilabel(app.UIFigure);
            app.knnFMEditFieldLabel.Text = 'knnFM';
            app.knnFMEditField = uieditfield(app.UIFigure, 'numeric');
            app.knnFMEditField.ValueChangedFcn = createCallbackFcn(app, @knnFMEditFieldValueChanged, true);
            app.fmThresEditFieldLabel = uilabel(app.UIFigure);
            app.fmThresEditFieldLabel.Text = 'fmThres';
            app.fmThresEditField = uieditfield(app.UIFigure, 'numeric');
            app.fmThresEditField.ValueChangedFcn = createCallbackFcn(app, @fmThresEditFieldValueChanged, true);
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.maxIterEditFieldLabel = uilabel(app.UIFigure);
            app.maxIterEditFieldLabel.Text = 'maxIter';
            app.maxIterEditField = uieditfield(app.UIFigure, 'numeric');
            app.maxIterEditField.ValueChangedFcn = createCallbackFcn(app, @maxIterEditFieldValueChanged, true);
            app.nSpheresEditFieldLabel = uilabel(app.UIFigure);
            app.nSpheresEditFieldLabel.Text = 'nSpheres';
            app.nSpheresEditField = uieditfield(app.UIFigure, 'numeric');
            app.nSpheresEditField.ValueChangedFcn = createCallbackFcn(app, @nSpheresEditFieldValueChanged, true);
            app.outlrThresEditFieldLabel = uilabel(app.UIFigure);
            app.outlrThresEditFieldLabel.Text = 'outlrThres';
            app.outlrThresEditField = uieditfield(app.UIFigure, 'numeric');
            app.outlrThresEditField.ValueChangedFcn = createCallbackFcn(app, @outlrThresEditFieldValueChanged, true);
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.TrackedLabel = uilabel(app.UIFigure);
            app.TrackedLabel.Text = 'Tracked';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TPTGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end