classdef regGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ContinueButton                matlab.ui.control.Button
        LoadGreensMatrixButton        matlab.ui.control.Button
        loadGreenField                matlab.ui.control.EditField
        RegularizationMethodDropDownLabel  matlab.ui.control.Label
        RegularizationMethodDropDown  matlab.ui.control.DropDown
        OutputnameEditFieldLabel      matlab.ui.control.Label
        OutputnameEditField           matlab.ui.control.EditField
        LoadNodalSolutionsButton      matlab.ui.control.Button
        loadNodeField                 matlab.ui.control.EditField
        SaveGreensMatrixButton        matlab.ui.control.Button
        saveGreenField                matlab.ui.control.EditField
        COMSOLGeometryButton          matlab.ui.control.Button
        loadComsolField               matlab.ui.control.EditField
        ParticleTrackingFileButton    matlab.ui.control.Button
        loadPTField                   matlab.ui.control.EditField
        LoadFileDropDownLabel         matlab.ui.control.Label
        LoadFileDropDown              matlab.ui.control.DropDown
        DisplacementSourceDropDownLabel  matlab.ui.control.Label
        DisplacementSourceDropDown    matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        closenum % When equal to 1, closes the GUI
        loadGreen % Description
        saveGreen % Description
        loadNodal % Description
        loadComsol % Description
        loadPT % Description
        loadFile % Description
        dispSource % Description
        outputName % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, inputs)
            
            %Initialize property variables
            app.closenum = 0;
            app.loadGreen = 'Load File';
            app.saveGreen = 'Load File';
            app.loadNodal = 'Load File';
            app.loadComsol = 'Load File';
            app.loadPT = 'Load File';
            app.loadFile = 'Yes';
            app.dispSource = 'Load from .mat file';
            app.outputName = 'Choose a name';
   
            %Load in inputs if present
            if isfield(inputs,'greensLoad')
                app.loadGreen = inputs.greensLoad;
                app.saveGreen = inputs.greensSave;
                app.loadNodal = inputs.nodalSoln;
                app.loadComsol = inputs.comsol;
                app.loadPT = inputs.loadPT;
                app.loadFile = inputs.loadFile;
                app.dispSource = inputs.dispSource;
                app.outputName = inputs.outputName;
            end
                
            %Initialize field and menu text/options
            app.loadGreenField.Value = app.loadGreen;
            app.saveGreenField.Value = app.saveGreen;
            app.loadNodeField.Value = app.loadNodal;
            app.loadComsolField.Value = app.loadComsol;
            app.loadPTField.Value = app.loadPT;
            app.OutputnameEditField.Value = app.outputName;
            app.LoadFileDropDown.Value = app.loadFile;
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            app.closenum = 1;
        end

        % Button pushed function: LoadGreensMatrixButton
        function LoadGreensMatrixButtonPushed(app, event)
            [baseName, folder] = uigetfile;
            value = fullfile(folder,baseName);
            app.loadGreen = value;
            app.loadGreenField.Value = value;
        end

        % Button pushed function: LoadNodalSolutionsButton
        function LoadNodalSolutionsButtonPushed(app, event)
            [baseName, folder] = uigetfile;
            value = fullfile(folder,baseName);
            app.loadNodal = value;
            app.loadNodeField.Value = value;
        end

        % Button pushed function: SaveGreensMatrixButton
        function SaveGreensMatrixButtonPushed(app, event)
            folder = uigetdir;
            value = fullfile(folder);
            app.saveGreen = value;
            app.saveGreenField.Value = value;
        end

        % Button pushed function: COMSOLGeometryButton
        function COMSOLGeometryButtonPushed(app, event)
            [baseName, folder] = uigetfile;
            value = fullfile(folder,baseName);
            app.loadComsol = value;
            app.loadComsolField.Value = value;
        end

        % Button pushed function: ParticleTrackingFileButton
        function ParticleTrackingFileButtonPushed(app, event)
            [baseName, folder] = uigetfile;
            value = fullfile(folder,baseName);
            app.loadPT = value;
            app.loadPTField.Value = value;
        end

        % Value changed function: OutputnameEditField
        function OutputnameEditFieldValueChanged(app, event)
            value = app.OutputnameEditField.Value;
            app.outputName = value;
        end

        % Value changed function: LoadFileDropDown
        function LoadFileDropDownValueChanged(app, event)
            value = app.LoadFileDropDown.Value;
            app.loadFile = value;
        end

        % Value changed function: DisplacementSourceDropDown
        function DisplacementSourceDropDownValueChanged(app, event)
            value = app.DisplacementSourceDropDown.Value;
            app.dispSource = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.LoadGreensMatrixButton = uibutton(app.UIFigure, 'push');
            app.LoadGreensMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGreensMatrixButtonPushed, true);
            app.LoadGreensMatrixButton.Text = 'Load Green''s Matrix';
            app.loadGreenField = uieditfield(app.UIFigure, 'text');
            app.RegularizationMethodDropDownLabel = uilabel(app.UIFigure);
            app.RegularizationMethodDropDownLabel.Text = 'Regularization Method';
            app.RegularizationMethodDropDown = uidropdown(app.UIFigure);
            app.RegularizationMethodDropDown.Items = {'Tikhonov'};
            app.RegularizationMethodDropDown.Value = 'Tikhonov';
            app.OutputnameEditFieldLabel = uilabel(app.UIFigure);
            app.OutputnameEditFieldLabel.Text = 'Output name';
            app.OutputnameEditField = uieditfield(app.UIFigure, 'text');
            app.OutputnameEditField.ValueChangedFcn = createCallbackFcn(app, @OutputnameEditFieldValueChanged, true);
            app.LoadNodalSolutionsButton = uibutton(app.UIFigure, 'push');
            app.LoadNodalSolutionsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNodalSolutionsButtonPushed, true);
            app.LoadNodalSolutionsButton.Text = 'Load Nodal Solutions';
            app.loadNodeField = uieditfield(app.UIFigure, 'text');
            app.SaveGreensMatrixButton = uibutton(app.UIFigure, 'push');
            app.SaveGreensMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @SaveGreensMatrixButtonPushed, true);
            app.SaveGreensMatrixButton.Text = 'Save Green''s Matrix';
            app.saveGreenField = uieditfield(app.UIFigure, 'text');
            app.COMSOLGeometryButton = uibutton(app.UIFigure, 'push');
            app.COMSOLGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @COMSOLGeometryButtonPushed, true);
            app.COMSOLGeometryButton.Text = 'COMSOL Geometry';
            app.loadComsolField = uieditfield(app.UIFigure, 'text');
            app.ParticleTrackingFileButton = uibutton(app.UIFigure, 'push');
            app.ParticleTrackingFileButton.ButtonPushedFcn = createCallbackFcn(app, @ParticleTrackingFileButtonPushed, true);
            app.ParticleTrackingFileButton.Text = 'Particle Tracking File';
            app.loadPTField = uieditfield(app.UIFigure, 'text');
            app.LoadFileDropDownLabel = uilabel(app.UIFigure);
            app.LoadFileDropDownLabel.Text = 'Load File?';
            app.LoadFileDropDown = uidropdown(app.UIFigure);
            app.LoadFileDropDown.Items = {'Yes', 'No'};
            app.LoadFileDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadFileDropDownValueChanged, true);
            app.LoadFileDropDown.Value = 'Yes';
            app.DisplacementSourceDropDownLabel = uilabel(app.UIFigure);
            app.DisplacementSourceDropDownLabel.Text = 'Displacement Source';
            app.DisplacementSourceDropDown = uidropdown(app.UIFigure);
            app.DisplacementSourceDropDown.Items = {'Predefined', 'Load from .mat file', 'Linear Combination'};
            app.DisplacementSourceDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplacementSourceDropDownValueChanged, true);
            app.DisplacementSourceDropDown.Value = 'Load from .mat file';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = regGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end