classdef bPassGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        ZStackSliderLabel               matlab.ui.control.Label
        ZStackSlider                    matlab.ui.control.Slider
        BandPassFilterSettingsLabel     matlab.ui.control.Label
        lnoiseEditFieldLabel            matlab.ui.control.Label
        lnoiseEditField                 matlab.ui.control.NumericEditField
        BPXEditFieldLabel               matlab.ui.control.Label
        BPXEditField                    matlab.ui.control.NumericEditField
        inputvEditFieldLabel            matlab.ui.control.Label
        inputvEditField                 matlab.ui.control.NumericEditField
        SubmitButton                    matlab.ui.control.Button
        ContinueButton                  matlab.ui.control.Button
        RedOriginalLabel                matlab.ui.control.Label
        CyanBandpassFilteredImageLabel  matlab.ui.control.Label
        WhiteOverlapLabel               matlab.ui.control.Label
        ThresholdSliderLabel            matlab.ui.control.Label
        ThresholdSlider                 matlab.ui.control.Slider
        ThresholdMultiplierEditFieldLabel  matlab.ui.control.Label
        ThresholdMultiplierEditField    matlab.ui.control.NumericEditField
        LocateParticlesButton           matlab.ui.control.Button
        BPYEditFieldLabel               matlab.ui.control.Label
        BPYEditField                    matlab.ui.control.NumericEditField
        BPZEditFieldLabel               matlab.ui.control.Label
        BPZEditField                    matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        Image % The image file
        bPassImage % The image after band Pass filter
        curframe % The current z stack frame
        threshimage % Thresholded image to show
        beadlocal % Bead locations found from locate beads
    end
    
    properties (Access = public)
        bpassParams % Band Pass filtering parameters
        finalbpassParams % Exported Band pass filtering parameters
        finalIBP % Exports IBP with settings chosen in gui
        closenum % When close = 1, code grabs the final values and exits app
        finalthresh % Exports threshold value determined when app closes
        finalmultithresh % Exports threshold multi value
        bpassX % Bandpass Parameter X
        bpassY % Bandpass Parameter Y
        bpassZ % Bandpass Parameter Z
    end
    
    methods (Access = private)
        
        function [] = bpassrun(app)
            %Runs the peak determination thresholding and reports back
            %image
            app.bpassParams{2} = [app.bpassX app.bpassY app.bpassZ];
            [app.bPassImage] = bpass3dMB(app.Image,app.bpassParams{1},app.bpassParams{2},app.bpassParams{3}); 
            %app.bPassImage = uint16(app.bPassImage);
            %app.bPassImage = prctile(IBP(:),app.finalthresh)*app.finalmultithresh;   %threshold set to be threshold_multi * 90
        end
        
        function [] = plotFigs(app)
            %This function when called grabs the current settings for the
            %bpass filter and thresholding and updates the plots
            imagesc(app.Image(:,:,app.curframe),'Parent',app.UIAxes)
            imagesc(app.threshimage(:,:,app.curframe),'Parent',app.UIAxes_2)
            %Compute the overlay of the two images, display them with
            %contrast on UIAxes2
            %contrastimage = cat(3,app.Image(:,:,app.curframe),app.threshimage(:,:,app.curframe),app.threshimage(:,:,app.curframe));
            fuse = imfuse(app.Image(:,:,app.curframe),app.threshimage(:,:,app.curframe),'falsecolor');
            imshow(fuse,'Parent',app.UIAxes2)
            
            %For now just loop through the beads and plot them to rounded
            %values (Only round in z)
            if app.beadlocal ~= 0
                zrbeadloc = round(app.beadlocal(:,3));
                rbeadloc = app.beadlocal;
                rbeadloc(:,3) = zrbeadloc;
                %disp((rbeadloc))
                %Sort only those on current frame
                cond = (rbeadloc(:,3) == app.curframe);
                curfbeads = rbeadloc(cond,:);
                disp(curfbeads)
                %plot scatter marks on top of existing graph
                hold(app.UIAxes2,'on')
                for i = 1:numel(curfbeads(:,1))
                    
                    scatter(app.UIAxes2,curfbeads(i,1),curfbeads(i,2),'+')
                    
                end
                hold(app.UIAxes2,'off')
            end
        end
        
        function [] = threshonly(app)
            percentile = prctile(app.bPassImage(:),app.finalthresh*100)*app.finalmultithresh;   %threshold set to be threshold_multi * 90
            %You can't multiply a uint16 and a logical so we convert the
            %mask
            disp(percentile)
            %%
            %disp(class(app.bPassImage))
            app.threshimage = app.bPassImage.*(app.bPassImage > percentile);
            %disp(app.threshimage(:,:,1))
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Image, bPassImage, bpassParams, threshold, threshmulti)
            %set size of slider and initial images on the axes
            slidemax = length(Image(1,1,:));
            app.ZStackSlider.Limits = [1, slidemax];
            app.Image = Image;
            app.bPassImage = bPassImage;
            app.closenum = 0;
            app.finalthresh = threshold;
            app.finalmultithresh = threshmulti;
            app.curframe = 1;
            app.beadlocal = 0;

            threshonly(app)
            %display images on graphs
            imagesc(Image(:,:,1),'Parent',app.UIAxes)
            imagesc(app.threshimage(:,:,1),'Parent',app.UIAxes_2)
            contrastimage = cat(3,app.Image(:,:,1),app.threshimage(:,:,1),app.threshimage(:,:,1));
            imagesc(contrastimage,'Parent',app.UIAxes2)
            %Set bpassparam fields
            app.bpassParams = bpassParams;
            app.lnoiseEditField.Value = app.bpassParams{1}(1);
            app.BPXEditField.Value = app.bpassParams{2}(1);
            app.BPYEditField.Value = app.bpassParams{2}(2);
            app.BPZEditField.Value = app.bpassParams{2}(3);
            app.bpassX = app.bpassParams{2}(1);
            app.bpassY = app.bpassParams{2}(2);
            app.bpassZ = app.bpassParams{2}(3);
            app.inputvEditField.Value = app.bpassParams{3}(1);
            app.ThresholdSlider.Value = threshold;
            app.ThresholdMultiplierEditField.Value = app.finalmultithresh;
        end

        % Value changing function: ZStackSlider
        function ZStackSliderValueChanging(app, event)
            changingValue = event.Value;
            changingValue = round(changingValue);
            app.curframe = changingValue;
            plotFigs(app)          
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
             app.finalbpassParams = app.bpassParams;
             app.finalIBP = app.bPassImage;
%             assignin('caller','finalIBP',app.finalIBP)
%             closereq
              app.closenum = 1;
        end

        % Value changed function: lnoiseEditField
        function lnoiseEditFieldValueChanged(app, event)
            value = app.lnoiseEditField.Value;
            app.bpassParams{1} = [value value value];
        end

        % Value changed function: BPXEditField
        function BPXEditFieldValueChanged(app, event)
            value = app.BPXEditField.Value;
            app.bpassX = value;
            app.bpassParams{2} = [app.bpassX app.bpassY app.bpassZ];
        end

        % Value changed function: inputvEditField
        function inputvEditFieldValueChanged(app, event)
            value = app.inputvEditField.Value;
            app.bpassParams{3} = [value value];
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            disp('Running band pass filter with settings...')
            lnoisetxt = ['lnoise = ', num2str(app.bpassParams{1})];
            disp(lnoisetxt)
            bpasstxt = ['Bandpass size = ',num2str(app.bpassParams{2})];
            disp(bpasstxt)
            inputbtxt = ['Inputb = ',num2str(app.bpassParams{3})];
            disp(inputbtxt)
            %Run bpassfilter again and update settings
            bpassrun(app)
            disp('Operation Completed')
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
%             assignin('caller','finalIBP',app.finalIBP)
             app.finalbpassParams = app.bpassParams;
             app.finalIBP = app.bPassImage;
%             assignin('caller','finalIBP',app.finalIBP)
%             closereq
             app.closenum = 1;             
             delete(app)        
        end

        % Callback function
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            app.finalthresh = value;
        end

        % Value changed function: ThresholdMultiplierEditField
        function ThresholdMultiplierEditFieldValueChanged(app, event)
            value = app.ThresholdMultiplierEditField.Value;
            app.finalmultithresh = value;
            %Run the thresholding code and update the plots
            threshonly(app)
            plotFigs(app)            
        end

        % Value changing function: ThresholdSlider
        function ThresholdSliderValueChanging(app, event)
            %changingValue = event.Value;
%             app.finalthresh = changingValue;
%             %Run the thresholding code and update the plots
%             threshonly(app)
%             plotFigs(app)   
        end

        % Button pushed function: LocateParticlesButton
        function LocateParticlesButtonPushed(app, event)
            %When button is pushed, run locate particles on current frame
            %only and overlay marks on the xy coordinates to show
            %localization accuracy in the overlay window. 
            disp('Locating Particles, this may take a moment!')
            x = locateParticles(app.threshimage,app.finalmultithresh,app.bpassParams{2},app.bpassParams,app.finalthresh);
            %We implemented parallel computing for this one cause lord that
            %takes forever in serial. 
            %Go ahead and plot those points on top of the overlay graph.
            %Store points in property
            app.beadlocal = x;
            %disp(app.beadlocal)            
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            value = app.ThresholdSlider.Value;
            app.finalthresh = value;
            %Run the thresholding code and update the plots
            threshonly(app)
            plotFigs(app)   
        end

        % Value changed function: BPYEditField
        function BPYEditFieldValueChanged(app, event)
            value = app.BPYEditField.Value;
            app.bpassY = value;
            app.bpassParams{2} = [app.bpassX app.bpassY app.bpassZ];
        end

        % Value changed function: BPZEditField
        function BPZEditFieldValueChanged(app, event)
            value = app.BPZEditField.Value;
            app.bpassZ = value;
            app.bpassParams{2} = [app.bpassX app.bpassY app.bpassZ];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Raw Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'BandPass filtered Image')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Overlay')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.ZStackSliderLabel = uilabel(app.UIFigure);
            app.ZStackSliderLabel.Text = 'Z Stack';
            app.ZStackSlider = uislider(app.UIFigure);
            app.ZStackSlider.ValueChangingFcn = createCallbackFcn(app, @ZStackSliderValueChanging, true);
            app.BandPassFilterSettingsLabel = uilabel(app.UIFigure);
            app.BandPassFilterSettingsLabel.Text = 'Band Pass Filter Settings';
            app.lnoiseEditFieldLabel = uilabel(app.UIFigure);
            app.lnoiseEditFieldLabel.Text = 'lnoise';
            app.lnoiseEditField = uieditfield(app.UIFigure, 'numeric');
            app.lnoiseEditField.ValueChangedFcn = createCallbackFcn(app, @lnoiseEditFieldValueChanged, true);
            app.BPXEditFieldLabel = uilabel(app.UIFigure);
            app.BPXEditFieldLabel.Text = 'BP X';
            app.BPXEditField = uieditfield(app.UIFigure, 'numeric');
            app.BPXEditField.ValueChangedFcn = createCallbackFcn(app, @BPXEditFieldValueChanged, true);
            app.inputvEditFieldLabel = uilabel(app.UIFigure);
            app.inputvEditFieldLabel.Text = 'inputv';
            app.inputvEditField = uieditfield(app.UIFigure, 'numeric');
            app.inputvEditField.Limits = [0 1];
            app.inputvEditField.RoundFractionalValues = 'on';
            app.inputvEditField.ValueChangedFcn = createCallbackFcn(app, @inputvEditFieldValueChanged, true);
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.RedOriginalLabel = uilabel(app.UIFigure);
            app.RedOriginalLabel.Text = 'Red: Original';
            app.CyanBandpassFilteredImageLabel = uilabel(app.UIFigure);
            app.CyanBandpassFilteredImageLabel.Text = 'Cyan: Bandpass Filtered Image';
            app.WhiteOverlapLabel = uilabel(app.UIFigure);
            app.WhiteOverlapLabel.Text = 'White: Overlap';
            app.ThresholdSliderLabel = uilabel(app.UIFigure);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.UIFigure);
            app.ThresholdSlider.Limits = [0 1];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdSliderValueChanging, true);
            app.ThresholdMultiplierEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdMultiplierEditFieldLabel.Text = 'Threshold Multiplier';
            app.ThresholdMultiplierEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdMultiplierEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdMultiplierEditFieldValueChanged, true);
            app.ThresholdMultiplierEditField.Value = 1;
            app.LocateParticlesButton = uibutton(app.UIFigure, 'push');
            app.LocateParticlesButton.ButtonPushedFcn = createCallbackFcn(app, @LocateParticlesButtonPushed, true);
            app.LocateParticlesButton.Text = 'Locate Particles';
            app.BPYEditFieldLabel = uilabel(app.UIFigure);
            app.BPYEditFieldLabel.Text = 'BP Y';
            app.BPYEditField = uieditfield(app.UIFigure, 'numeric');
            app.BPYEditField.ValueChangedFcn = createCallbackFcn(app, @BPYEditFieldValueChanged, true);
            app.BPZEditFieldLabel = uilabel(app.UIFigure);
            app.BPZEditFieldLabel.Text = 'BP Z';
            app.BPZEditField = uieditfield(app.UIFigure, 'numeric');
            app.BPZEditField.ValueChangedFcn = createCallbackFcn(app, @BPZEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bPassGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end