classdef finalViewGUI_OriginalLocations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        ZStackSliderLabel  matlab.ui.control.Label
        ZStackSlider       matlab.ui.control.Slider
        TimeSliderLabel    matlab.ui.control.Label
        TimeSlider         matlab.ui.control.Slider
        ContinueButton     matlab.ui.control.Button
        loadinglabel       matlab.ui.control.Label
        OverlayCellButton  matlab.ui.control.Button
        RemoveCellButton   matlab.ui.control.Button
        PlaneLabel         matlab.ui.control.Label
        PlaneSelector      matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        displacements % Displacements between matches
        matches % Matched beads between images
        tiffs % Orginal Image tiffs
        images % Cell storing loaded tiffs
        zVal % ZStack number
        tVal % Time point number
        cellLoad % If 0, no cell image is loaded, if 1 cell image is loaded
        cellImages % Cell containing images of cell
        plane % The desired imaging plane to image through. 
        driftStore % Cell array that holds the drift values for registering initial images
        originalLocs % Original Bead Locations
        dispWithDrift % DisplacementsWithDrift
    end
    
    properties (Access = public)
        closenum % When close = 1, code grabs the final values and exits app
    end
    
    methods (Access = private)
        
        function [] = plotAxes(app,zVal,tVal)
            curImage = app.images{tVal}(:,:,zVal);
            test = app.tiffs;
            test = test(tVal).name;
            disp(test)
            curDrift = app.driftStore{tVal};
            
            
            %disp(curDrift)
            relaxBeads = app.originalLocs{1,47}{1};
            
            % So for now we are ignoring z drift because we would need to
            % do the drift correction as we load the images in the startup
            % function which we can do, but might cause some part of the
            % code to act weird. Will investigate - MH 6/30/2021
            
            %driftCorrectedImage = curImage ; 
            %imstranslate works on 3D images, but we have to give it the
            %full 3D image then somehow load overthing into 3D but
            %display in 2D? Things might just be wonky because we are
            %missing the z component of this translation. 
            
            %driftCorrectedImage = imtranslate(app.images{tVal}, -1.*curDrift);
            driftCorrectedImage = imtranslate(app.images{tVal}(:,:,zVal), -1.*curDrift(1:2));
            
            %imshow(curImage,[1500,4000],'Parent',app.UIAxes)
            imshow(driftCorrectedImage(:,:),[1500,4000],'Parent',app.UIAxes)
            disp('Done')
            %plot approximate bead locations at each time point
            curBeads = app.matches{tVal,1}(:,4:6);
            curBeads(:,3) = curBeads(:,3) + curDrift(3);
            %test = app.originalLocs{tVal,1}{1};
            %curBeads = app.originalLocs{1,tVal}{1};
            sfBeads = app.matches{tVal,1}(:,1:3);
            sfBeads(:,3) = sfBeads(:,3) + curDrift(3);
            %round z positions and filter curBeads/sfBeads
            zRound = round(curBeads(:,3));
            zsfRound = round(sfBeads(:,3));
            zrelaxRound = round(relaxBeads(:,3));
            
            relaxBeads(:,3) = zrelaxRound;
            sfBeads(:,3) = zsfRound;
            curBeads(:,3) = zRound;
            
            %disp(zRound)
            %It would be nice to grab beads that are +/- 2 or so z stacks.
            cond = (curBeads(:,3) >= (app.zVal - 2) & curBeads(:,3) <= (app.zVal + 2));

            %disp(app.zVal)
            %cond = xor(curBeads(:,3) >= (app.zVal - 2), curBeads(:,3) <= (app.zVal + 2));    
            totZstacks = numel(app.images{1}(1,1,:));
            %disp(totZstacks)
            if app.zVal >= totZstacks -2
                cond = curBeads(:,3) >= (app.zVal - 3);
            end
            %disp(relaxBeads(1:5,:))
            curBeads = curBeads(cond,:);
            sfBeads = sfBeads(cond,:);
            relaxBeads = relaxBeads(cond,:);
            %disp(curBeads)
            %Get current relevant displacements and plot with quiver
            
            %We need to undo drift correction sometimes
            
            curDisps = app.displacements{tVal,1}(cond,1:2);
            
            curDriftedDisps = app.dispWithDrift{tVal,1}(cond,1:2);
            
            curDispsCalc = curBeads - sfBeads;
            
            %So issue is that because of our z rounding the two lists are
            %not necessarily the same one? Sometimes it lines up but other
            %times it does not. 
            
            %disp(app.displacements{tVal,1})
            %Option to grab all of the displacements
            %curDisps = app.displacements{tVal,1}(1:2);
            %disp(curDisps)
            %quiver plot beads with displacement and stress free beads with
            %crosses
            disp(curDrift)
            scale = 1;
            
            %add overlay of cell if it is provided
            if app.cellLoad == 1
                %imshowpair(app.cellImages{tVal}(:,:,zVal),app.images{tVal}(:,:,zVal),'Parent',app.UIAxes)
                imshow(app.cellImages{tVal}(:,:,zVal),[],'Parent',app.UIAxes)
                hold(app.UIAxes,'on')
                title(app.UIAxes,'Displacements')
                quiver(app.UIAxes,sfBeads(:,1),sfBeads(:,2),scale*curDisps(:,1),scale*curDisps(:,2),0,'m', 'LineWidth', 1.5)
                %scatter(app.UIAxes, sfBeads(:,1), sfBeads(:,2),'+')
                %Option to save to folder
                
                fig = imshow(app.cellImages{tVal}(:,:,zVal),[]);
                hold 'on'
                title('Displacements')
                quiver(sfBeads(:,1),sfBeads(:,2),scale*curDisps(:,1),scale*curDisps(:,2),0,'m', 'LineWidth', 1.5)
                saveas(fig, ['C:\Users\18287\Desktop\New folder\Poster Images\', num2str(zVal),'.tif'])
                hold 'off'
                close 
                
                
            else
                hold(app.UIAxes,'on')
                %We want the quiver to start from the original bead
                %locations, so the relaxed state beads. 
                quiver(app.UIAxes,sfBeads(:,1)+1,sfBeads(:,2)+ 1 ...
                   ,scale*curDispsCalc(:,1),scale*curDispsCalc(:,2),0,'m', 'LineWidth',2)
                title(app.UIAxes,'Displacements')
                scatter(app.UIAxes, curBeads(:,1) +1, curBeads(:,2) +1,'+','r')
                scatter(app.UIAxes,sfBeads(:,1)+1, sfBeads(:,2)+1,'o','b')
                %Also show the locations of the original beads? 
                %The +1 accounts for MATLAB counting from 1 for some
                %reason.
            end

            hold(app.UIAxes,'off')

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, displacements, matches, tiffs, drift, x, DriftDisps)
            app.displacements = displacements;
            app.matches = matches;
            app.tiffs = tiffs; 
            app.images = cell([numel(app.tiffs), 1]);
            app.loadinglabel.Text = 'Loading Images';
            app.closenum = 0;
            app.cellLoad = 0;
            app.cellImages = {};
            app.plane = 'XY';
            app.driftStore = drift;
            app.originalLocs = x;
            app.dispWithDrift = DriftDisps;
            %Load in each tiff so we can sample them quickly
            for i = 1:numel(app.tiffs)
                disp(['Loading Image ' num2str(i) ' of ' num2str(numel(app.tiffs))])
                file = [app.tiffs(i).folder, '\', app.tiffs(i).name];
                app.images{i} = loadtiff(file);
            end
            app.loadinglabel.Text = 'Images Loaded!';
            %Initialize Sliders
            app.TimeSlider.Limits = [1, numel(tiffs)];
            app.ZStackSlider.Limits = [1, numel(app.images{1}(1,1,:))];
            app.zVal = 1;
            app.tVal = 1;
            %Load first image on the axes
            plotAxes(app,app.zVal,app.tVal)

        end

        % Value changing function: ZStackSlider
        function ZStackSliderValueChanging(app, event)
            changingValue = event.Value;
            changingValue = round(changingValue);
            if app.zVal ~= changingValue
                app.zVal = changingValue;
                %disp(app.zVal)
                %plot on axes
                plotAxes(app,app.zVal,app.tVal)
            end
        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            %[] = app.TimeSlider.Value;
            
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)
            changingValue = event.Value;
            changingValue = round(changingValue);
            
            %plot on axes only if value is different from current value
            if app.tVal ~= changingValue
               app.tVal = changingValue;
               plotAxes(app,app.zVal,app.tVal)
            end
            
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            app.closenum = 1;
        end

        % Button pushed function: OverlayCellButton
        function OverlayCellButtonPushed(app, event)
            %get folder directory containing cells
            cellFold = uigetdir;
            %Find TIFs in folder
            tifs = dir(cellFold);
            tifs=tifs(~ismember({tifs.name},{'.','..'}));
            fileNames = cell(length(tifs),1);
            app.cellImages = cell(length(tifs),1);
            for k = 1:length(tifs)
                fileNames{k,1} = [tifs(k).folder '\' tifs(k).name];
                disp(fileNames{k,1})
                app.cellImages{k} = loadtiff(fileNames{k,1});
            end
            app.cellLoad = 1;
        end

        % Button pushed function: RemoveCellButton
        function RemoveCellButtonPushed(app, event)
            app.cellLoad = 0;
        end

        % Value changed function: PlaneSelector
        function PlaneSelectorValueChanged(app, event)
            value = app.PlaneSelector.Value;
            app.plane = value;
            %type(value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ZStackSliderLabel = uilabel(app.UIFigure);
            app.ZStackSliderLabel.Text = 'Z-Stack';
            app.ZStackSlider = uislider(app.UIFigure);
            app.ZStackSlider.ValueChangingFcn = createCallbackFcn(app, @ZStackSliderValueChanging, true);
            app.TimeSliderLabel = uilabel(app.UIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.UIFigure);
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.loadinglabel = uilabel(app.UIFigure);
            app.OverlayCellButton = uibutton(app.UIFigure, 'push');
            app.OverlayCellButton.ButtonPushedFcn = createCallbackFcn(app, @OverlayCellButtonPushed, true);
            app.OverlayCellButton.Text = 'Overlay Cell';
            app.RemoveCellButton = uibutton(app.UIFigure, 'push');
            app.RemoveCellButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveCellButtonPushed, true);
            app.RemoveCellButton.Text = 'Remove Cell';
            app.PlaneLabel = uilabel(app.UIFigure);
            app.PlaneLabel.Text = 'Plane';
            app.PlaneSelector = uidropdown(app.UIFigure);
            app.PlaneSelector.Items = {'XY', 'YZ', 'XZ'};
            app.PlaneSelector.ValueChangedFcn = createCallbackFcn(app, @PlaneSelectorValueChanged, true);
            app.PlaneSelector.Value = 'XY';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalViewGUI_OriginalLocations(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end