classdef imageAnalysisGUI2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        ContinueButton           matlab.ui.control.Button
        ViewerDropDownLabel      matlab.ui.control.Label
        ViewerDropDown           matlab.ui.control.DropDown
        AddCellButton            matlab.ui.control.Button
        RemoveCellButton         matlab.ui.control.Button
        TimeSliderLabel          matlab.ui.control.Label
        TimeSlider               matlab.ui.control.Slider
        ScaleEditFieldLabel      matlab.ui.control.Label
        ScaleEditField           matlab.ui.control.NumericEditField
        ComputeStatisticsButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        displacements % Description
        tractions % Description
        mesh % Description
        xdf % Tractions in X direction
        ydf % Tractions in Y direction
        zdf % Tractions in Z direction
        xdx % Displacements in X
        ydy % Displacements in Y
        zdz % Displacements in Z
        curTime % Current Time Selected Time Point
        cellImage % Cell array holding tif stacks of cells
        vector % If = 0, plots displacements, if = 1 plots forces
        dispMap % Description
        plotCell % if = 0, do not plot cell, if = 1 plot cell
        trans % Image translation between imshow and quiver plots
        scale % Vector scaling parameter
        masks % Cell boundary masks
    end
    
    properties (Access = public)
        closenum % When equals 1, close the GUI
    end
    
    methods (Access = private)
        
        function [] = plotVectors(app)
            %Parses variables and plots on axis the selected options
            if app.vector == 0 
               dx = app.xdx;
               dy = app.ydy;
               %dz = app.zdz;
               x = app.dispMap(:,1);
               y = app.dispMap(:,2);
               %z = app.dispMap(:,3);
               app.UIAxes.Title.String = 'Displacements';
            end

            if app.vector == 1 
               dx = app.xdf;
               dy = app.ydf;
               %dz = app.zdf;  
               x = app.mesh(:,1);
               y = app.mesh(:,2);
               %z = app.mesh(:,3);
               app.UIAxes.Title.String = 'Traction Forces';
            end
                
            %Plot cell if present
            if app.plotCell == 1
                hold(app.UIAxes, "on")      
                imshow(app.cellImage{app.curTime},'Parent',app.UIAxes)
            end

            quiver(app.UIAxes,x + app.trans(1),y + app.trans(2),dx,dy,app.scale)
            
            hold(app.UIAxes, "off")
        end
        
        function [] = adjustTime(app)
            %Change mesh/coordinates and vectors based on current time
            app.xdf = app.tractions{app.curTime}(1:3:end);
            app.ydf = app.tractions{app.curTime}(2:3:end);
            app.zdf = app.tractions{app.curTime}(3:3:end);
        
            app.xdx = app.displacements{app.curTime}(1:3:end);
            app.ydy = app.displacements{app.curTime}(2:3:end);
            app.zdz = app.displacements{app.curTime}(3:3:end);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, displacements, tractions, mesh, dispMap, trans)
            app.displacements = displacements;
            app.tractions = tractions;
            app.mesh = mesh;
            app.cellImage = {};
            app.dispMap = dispMap;
            app.vector = 0;
            app.plotCell = 0;
            app.masks = cell(numel(tractions));
            app.closenum = 0;
            %If only one time is present, make time slider invisible
            if numel(tractions) == 1
               app.TimeSlider.Visible = "off"; 
               app.TimeSliderLabel.Visible = "off";
            else
                app.TimeSlider.Limits = [1, numel(tractions)];
            end
            
            app.TimeSlider.Value = 1;
            app.trans = trans;
            app.scale = 1;
            app.ScaleEditField.Value = 1;
            %Intialize the tractions/displacements at the first time point
            app.xdf = tractions{1}(1:3:end);
            app.ydf = tractions{1}(2:3:end);
            app.zdf = tractions{1}(3:3:end);
        
            app.xdx = displacements{1}(1:3:end);
            app.ydy = displacements{1}(2:3:end);
            app.zdz = displacements{1}(3:3:end);
        
            app.curTime = 1;
            
            %3D plot first time point with Forces
            plotVectors(app)
            %quiver(app.UIAxes,app.mesh(:,1),app.mesh(:,2),app.xdf,app.ydf)

        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            value = app.TimeSlider.Value;
            app.curTime = value;
            adjustTime(app)
            %Update the plots based on time
            plotVectors(app)
        end

        % Button pushed function: AddCellButton
        function AddCellButtonPushed(app, event)
            %Get cell files from folder and load them
            folder = uigetdir;
            path = dir(fullfile(folder, '*.tif'));
            %Remove . and .. entries
            path=path(~ismember({path.name},{'.','..'}));       
            
            for i = 1:numel(path)
               app.cellImage{i} = imread(fullfile(path(i).folder,path(i).name)); 
               
            end
            
            app.plotCell = 1;
            
            plotVectors(app)
        end

        % Value changed function: ViewerDropDown
        function ViewerDropDownValueChanged(app, event)
            value = app.ViewerDropDown.Value;
            if strcmp(value, 'Bead Displacements')
                app.vector = 0;
            end
            
            if strcmp(value,'Recovered Tractions')
                app.vector = 1;
            end
            
            plotVectors(app)
        end

        % Button pushed function: RemoveCellButton
        function RemoveCellButtonPushed(app, event)
            app.plotCell = 0;
            plotVectors(app)
        end

        % Value changed function: ScaleEditField
        function ScaleEditFieldValueChanged(app, event)
            value = app.ScaleEditField.Value;
            app.scale = value;
            plotVectors(app)
        end

        % Button pushed function: ComputeStatisticsButton
        function ComputeStatisticsButtonPushed(app, event)
            %Compute cell boundary; assume one cell and is the largest
            %object, provide the cell mask 
            folder = uigetdir;
            path = dir(fullfile(folder, '*.tif'));
            %Remove . and .. entries
            path=path(~ismember({path.name},{'.','..'}));       
            
            %Open masks, get boundaries of masks
            for i = 1:numel(path)
               im = imread(fullfile(path(i).folder,path(i).name));
               %disp(size(im))
               im = bwareafilt(imbinarize(im),1);
               bound = bwboundaries(im,'noholes');
               app.masks{i} = bound{1};
            end
            
            %Run dataStats at each time point 
            for j = 1:numel(app.masks)
               timePt = j;
               disp(['Computing Statistics for Time Point ', num2str(timePt)])
               curMask = app.masks{j,1};
               
               %Get current traction/disp fields
               curDisp = app.displacements{j,1};
               curTract = app.tractions{j,1};

               
               dataStats(curMask,app.dispMap,app.mesh,app.trans,curTract, ...
                   curDisp, timePt)
               
            end
            
            disp('Processing Complete')

        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            app.closenum = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.ViewerDropDownLabel = uilabel(app.UIFigure);
            app.ViewerDropDownLabel.Text = 'Viewer';
            app.ViewerDropDown = uidropdown(app.UIFigure);
            app.ViewerDropDown.Items = {'Bead Displacements', 'Recovered Tractions'};
            app.ViewerDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewerDropDownValueChanged, true);
            app.ViewerDropDown.Value = 'Bead Displacements';
            app.AddCellButton = uibutton(app.UIFigure, 'push');
            app.AddCellButton.ButtonPushedFcn = createCallbackFcn(app, @AddCellButtonPushed, true);
            app.AddCellButton.Text = 'Add Cell';
            app.RemoveCellButton = uibutton(app.UIFigure, 'push');
            app.RemoveCellButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveCellButtonPushed, true);
            app.RemoveCellButton.Text = 'Remove Cell';
            app.TimeSliderLabel = uilabel(app.UIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.UIFigure);
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.ScaleEditFieldLabel = uilabel(app.UIFigure);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScaleEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleEditFieldValueChanged, true);
            app.ComputeStatisticsButton = uibutton(app.UIFigure, 'push');
            app.ComputeStatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeStatisticsButtonPushed, true);
            app.ComputeStatisticsButton.Text = 'Compute Statistics';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imageAnalysisGUI2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end