classdef swellingCorrectionGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FeatureListBox          matlab.ui.control.ListBox
        FeatureListBoxLabel     matlab.ui.control.Label
        ZScaleEditField         matlab.ui.control.NumericEditField
        ZScaleEditFieldLabel    matlab.ui.control.Label
        YScaleEditField         matlab.ui.control.NumericEditField
        YScaleEditField_2Label  matlab.ui.control.Label
        XScaleEditField         matlab.ui.control.NumericEditField
        XScaleEditField_2Label  matlab.ui.control.Label
        ContinueButton          matlab.ui.control.Button
        LoadSwellingDataButton  matlab.ui.control.Button
        UIAxes_3                matlab.ui.control.UIAxes
        UIAxes_2                matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        beadLocs % nx3 matrix of reference bead locations
        beadLocs2 % nx3 matrix of time1 bead locations for comparison
        alignedElements % Element centroids after alignment
        alignedDisps % Aligned displacements
        scaleX % X scaling factor to apply to swelling correction
        scaleY % Y scaling factor to apply to swelling correction
        scaleZ % Z scaling factor to apply to swelling correction
        corBeads % nx3 Corrected Bead locations
        newElemsDisps % nx6 matrix of positions and displacements to scale
        feature % Straight, Confinement, or Squiggle
    end
    
    properties (Access = public)
        finalBeads % nx3 Final reference bead positions
        closenum % flag to close the GUI
        finalScale % Description
    end
    
    methods (Access = private)
        
        function updatePlots(app)
            %Updates each plotting functions with current scale factors
            app.corBeads = [app.newElemsDisps{1}(:,1) + (app.scaleX.*app.newElemsDisps{1}(:,4)), app.newElemsDisps{1}(:,2) + ...
                    (app.scaleY.*app.newElemsDisps{1}(:,5)), app.newElemsDisps{1}(:,3) + (app.scaleZ.*app.newElemsDisps{1}(:,6))];

            scatter(app.beadLocs(:,1), app.beadLocs(:,2),'Parent', app.UIAxes)
            hold(app.UIAxes,'on') 
            scatter(app.beadLocs2(:,1), app.beadLocs2(:,2),'Parent', app.UIAxes)
            scatter(app.corBeads(:,1), app.corBeads(:,2), 'Parent',app.UIAxes)
            legend(app.UIAxes,'Reference Beads','Experiment Beads','Corrected Beads')
            axis(app.UIAxes,'equal')
            hold(app.UIAxes,'off') 

            scatter(app.beadLocs(:,1), app.beadLocs(:,3),'Parent', app.UIAxes_2)
            hold(app.UIAxes_2,'on')  
            scatter(app.beadLocs2(:,1), app.beadLocs2(:,3),'Parent', app.UIAxes_2)
            scatter(app.corBeads(:,1), app.corBeads(:,3), 'Parent',app.UIAxes_2)
            legend(app.UIAxes_2,'Reference Beads','Experiment Beads','Corrected Beads')
            axis(app.UIAxes_2,'equal')
            hold(app.UIAxes_2,'off')  
            
            scatter(app.beadLocs(:,2), app.beadLocs(:,3),'Parent', app.UIAxes_3)
            hold(app.UIAxes_3,'on') 
            scatter(app.beadLocs2(:,2), app.beadLocs2(:,3),'Parent', app.UIAxes_3)
            scatter(app.corBeads(:,2), app.corBeads(:,3), 'Parent',app.UIAxes_3)
            legend(app.UIAxes_3,'Reference Beads','Experiment Beads','Corrected Beads')
            axis(app.UIAxes_3,'equal')
            hold(app.UIAxes_3,'off') 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, beadLocs, beadLocs2)
            app.beadLocs = beadLocs;
            app.beadLocs2 = beadLocs2;
            app.feature = 'Straight';

            %Make initial plots of each axes from beadLocs
            scatter(beadLocs(:,1), beadLocs(:,2),'Parent', app.UIAxes)
            hold(app.UIAxes,'on') 
            scatter(beadLocs2(:,1), beadLocs2(:,2),'Parent', app.UIAxes)
            legend(app.UIAxes,'Reference Beads','Experiment Beads')
            axis(app.UIAxes,'equal')
            hold(app.UIAxes,'off') 

            scatter(beadLocs(:,1), beadLocs(:,3),'Parent', app.UIAxes_2)
            hold(app.UIAxes_2,'on')  
            scatter(beadLocs2(:,1), beadLocs2(:,3),'Parent', app.UIAxes_2)
            legend(app.UIAxes_2,'Reference Beads','Experiment Beads')
            axis(app.UIAxes_2,'equal')
            hold(app.UIAxes_2,'off')  
            
            scatter(beadLocs(:,2), beadLocs(:,3),'Parent', app.UIAxes_3)
            hold(app.UIAxes_3,'on') 
            scatter(beadLocs2(:,2), beadLocs2(:,3),'Parent', app.UIAxes_3)
            legend(app.UIAxes_3,'Reference Beads','Experiment Beads')
            axis(app.UIAxes_3,'equal')
            hold(app.UIAxes_3,'off') 

            app.scaleX = 1;
            app.scaleY = 1;
            app.scaleZ = 1;

            app.closenum = 0;
        end

        % Button pushed function: LoadSwellingDataButton
        function LoadSwellingDataButtonPushed(app, event)
            %Load swelling data (nx6 matrix of nx1:3 positions, nx4:6 of
            %displacements from averaged swelling. Perform automated
            %alignment to provided bead field. 

            load(uigetfile()); %Ideally this file is already in units of pixels and has a consistent naming scheme
            
            %Center elemCents2D
                        
            elemCents2D = newCentroids;
%             elemCents2D(:,1:2) = elemCents2D(:,1:2)/.199;
%             elemCents2D(:,3) = elemCents2D(:,3)/.8;
            averageDisplacement = finalDisps;
%             averageDisplacement(:,1:2) = averageDisplacement(:,1:2)/.199;
%             averageDisplacement(:,3) = averageDisplacement(:,3)/.8;
            referenceBeads = app.beadLocs;
            %perform automated alignment to beadLocs using PointCloud
            %registration
            PC1 = pointCloud(elemCents2D);
            PC2 = pointCloud(referenceBeads);
            tform = pcregistericp(PC1, PC2);
            PC1reg = pctransform(PC1,tform);
            finalElements = PC1reg.Location;

            %Channels should already be aligned, do interpolation 
            %[finalElements,finalDisplacements,~] = alignChannels([elemCents2D,averageDisplacement],app.beadLocs,1);
            % if strcmp(app.feature,'Straight')
            %     finalElements = alignOriginStraight(elemCents2D,0);
            % elseif strcmp(app.feature,'Confinement')
            %     finalElements = alignOrigin(elemCents2D,0);
            % end
            % 
            finalDisplacements = averageDisplacement;
            app.alignedDisps = finalDisplacements;
            app.alignedElements = finalElements;

            %Correct bead locations/displacements
            
            app.newElemsDisps = interpDisps({[finalElements(:,1),finalElements(:,2), finalElements(:,3), ...
                    finalElements(:,1) + finalDisplacements(:,1)...
                    , finalElements(:,2) + finalDisplacements(:,2)...
                    , finalElements(:,3) + finalDisplacements(:,3)]},referenceBeads);

            app.corBeads = [app.newElemsDisps{1}(:,1) + (app.scaleX.*app.newElemsDisps{1}(:,4)), app.newElemsDisps{1}(:,2) + ...
                    (app.scaleY.*app.newElemsDisps{1}(:,5)), app.newElemsDisps{1}(:,3) + (app.scaleZ.*app.newElemsDisps{1}(:,6))];
            %Add in another alignment step
%             PC1 = pointCloud(app.corBeads);
%             PC2 = pointCloud(app.beadLocs2);
%             tform = pcregistericp(PC1, PC2);
%             PC1reg = pctransform(PC1,tform);
%             app.corBeads = PC1reg.Location;
            %Update plots now with scatter plots
            app.updatePlots()
        end

        % Value changed function: XScaleEditField
        function XScaleEditFieldValueChanged(app, event)
            value = app.XScaleEditField.Value;
            app.scaleX = value;
            app.updatePlots()
        end

        % Value changed function: YScaleEditField
        function YScaleEditFieldValueChanged(app, event)
            value = app.YScaleEditField.Value;
            app.scaleY = value;
            app.updatePlots()
        end

        % Value changed function: ZScaleEditField
        function ZScaleEditFieldValueChanged(app, event)
            value = app.ZScaleEditField.Value;
            app.scaleZ = value;
            app.updatePlots()
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            app.finalBeads = app.corBeads;
            app.closenum = 1;
        end

        % Value changed function: FeatureListBox
        function FeatureListBoxValueChanged(app, event)
            value = app.FeatureListBox.Value;
            app.feature = value;
            %value
            %app.feature
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'XY MIP')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'XZ MIP')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Z')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'YZ MIP')
            xlabel(app.UIAxes_3, 'Y')
            ylabel(app.UIAxes_3, 'Z')
            zlabel(app.UIAxes_3, 'Z')
            app.LoadSwellingDataButton = uibutton(app.UIFigure, 'push');
            app.LoadSwellingDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSwellingDataButtonPushed, true);
            app.LoadSwellingDataButton.Text = 'Load Swelling Data';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.XScaleEditField_2Label = uilabel(app.UIFigure);
            app.XScaleEditField_2Label.Text = 'X Scale';
            app.XScaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.XScaleEditField.ValueChangedFcn = createCallbackFcn(app, @XScaleEditFieldValueChanged, true);
            app.XScaleEditField.Value = 1;
            app.YScaleEditField_2Label = uilabel(app.UIFigure);
            app.YScaleEditField_2Label.Text = 'Y Scale';
            app.YScaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.YScaleEditField.ValueChangedFcn = createCallbackFcn(app, @YScaleEditFieldValueChanged, true);
            app.YScaleEditField.Value = 1;
            app.ZScaleEditFieldLabel = uilabel(app.UIFigure);
            app.ZScaleEditFieldLabel.Text = 'Z Scale';
            app.ZScaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZScaleEditField.ValueChangedFcn = createCallbackFcn(app, @ZScaleEditFieldValueChanged, true);
            app.ZScaleEditField.Value = 1;
            app.FeatureListBoxLabel = uilabel(app.UIFigure);
            app.FeatureListBoxLabel.Text = 'Feature';
            app.FeatureListBox = uilistbox(app.UIFigure);
            app.FeatureListBox.Items = {'Straight', 'Confinement', 'Squiggle'};
            app.FeatureListBox.ValueChangedFcn = createCallbackFcn(app, @FeatureListBoxValueChanged, true);
            app.FeatureListBox.Value = 'Straight';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = swellingCorrectionGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end