classdef userInputGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        outlrThresEditField        matlab.ui.control.NumericEditField
        outlrThresEditFieldLabel   matlab.ui.control.Label
        knnFMEditField             matlab.ui.control.NumericEditField
        knnFMEditField_2Label      matlab.ui.control.Label
        fmThresEditField           matlab.ui.control.NumericEditField
        fmThresEditFieldLabel      matlab.ui.control.Label
        knnFDEditField             matlab.ui.control.NumericEditField
        knnFDEditFieldLabel        matlab.ui.control.Label
        TPTParametersLabel         matlab.ui.control.Label
        MultiplierEditField        matlab.ui.control.NumericEditField
        MultiplierEditFieldLabel   matlab.ui.control.Label
        IntensityEditField         matlab.ui.control.NumericEditField
        IntensityEditFieldLabel    matlab.ui.control.Label
        WinSizeEditField           matlab.ui.control.NumericEditField
        WinSizeEditFieldLabel      matlab.ui.control.Label
        MaxSizeEditField           matlab.ui.control.NumericEditField
        MaxSizeEditFieldLabel      matlab.ui.control.Label
        MinSizeEditField           matlab.ui.control.NumericEditField
        MinSizeEditFieldLabel      matlab.ui.control.Label
        ThresholdEditField         matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel    matlab.ui.control.Label
        BeadParametersLabel        matlab.ui.control.Label
        ZEditField                 matlab.ui.control.NumericEditField
        ZEditFieldLabel            matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        XEditFieldLabel            matlab.ui.control.Label
        VoxelSizeumLabel           matlab.ui.control.Label
        AnalysisNameField          matlab.ui.control.EditField
        AnalysisNameLabel          matlab.ui.control.Label
        PreTreatmentChannelField   matlab.ui.control.NumericEditField
        PostTreatmentChannelField  matlab.ui.control.NumericEditField
        ChannelLabel               matlab.ui.control.Label
        ContinueButton             matlab.ui.control.Button
        AnalysisOutputFolderLabel  matlab.ui.control.Label
        PreTreatmentFolderLabel    matlab.ui.control.Label
        PostTreatmentFileLabel     matlab.ui.control.Label
        AnalysisOutputField        matlab.ui.control.EditField
        PreTreatmentField          matlab.ui.control.EditField
        StressFreeFileEditField    matlab.ui.control.EditField
        AnalysisOutputbutton       matlab.ui.control.Button
        PreTreatmentbutton         matlab.ui.control.Button
        SelectButton               matlab.ui.control.Button
        isCZICheckBox              matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Initialize fields with values
            app.StressFreeFileEditField.Value = strcat(evalin('base','postStem'),evalin('base','postTreatmentFile'));
            app.PreTreatmentField.Value = strcat(evalin('base','preStem')); %,evalin('base','preTreatmentFileName'));
            app.isCZICheckBox.Value = evalin('base','isCZI');
            app.AnalysisOutputField.Value = evalin('base','analysisStem');
            app.AnalysisNameField.Value = evalin('base','dataFileName');
            app.XEditField.Value = evalin('base','size_x');
            app.YEditField.Value = evalin('base','size_y');
            app.ZEditField.Value = evalin('base','size_z');
            app.MinSizeEditField.Value = evalin('base','beadmin');
            app.MaxSizeEditField.Value = evalin('base','beadmax');
            app.WinSizeEditField.Value = evalin('base','beadwin');
            app.IntensityEditField.Value = evalin('base','beadint');
            app.MultiplierEditField.Value = evalin('base','beadmult');
            app.knnFDEditField.Value = evalin('base','tptknnFD');
            app.knnFMEditField.Value = evalin('base','tptknnFD');
            app.fmThresEditField.Value = evalin('base','tptfmThres');
            app.outlrThresEditField.Value = evalin('base','tptoutThres');
            %Initialize channel fields with values
            pTchannel = evalin('base','postTreatmentChannel');
            preTchannel = evalin('base','preTreatmentChannel');
            if isnan(pTchannel) 
               app.PostTreatmentChannelField.Value = 0;
            else
               app.PostTreatmentChannelField.Value = pTchannel;
            end
            if iscell(preTchannel)
                if isnan(preTchannel{1}) 
                   app.PreTreatmentChannelField.Value = 0;
                else
                   app.PreTreatmentChannelField.Value = preTchannel;
                end
            else
                app.PreTreatmentChannelField.Value = preTchannel;
            end
        end

        % Value changed function: isCZICheckBox
        function isCZICheckBoxValueChanged(app, event)
            value = app.isCZICheckBox.Value;
            if value == true
                value = 1;
            end
            assignin('base','isCZI',value);
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            closereq
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            %get post treatment file, extract folder path for stem
            [postTname,postTpath] = uigetfile('*.tiff');
            assignin('base','postStem',postTpath);            
            assignin('base','postTreatmentFile',postTname);
            %after setting, change the text field value
            app.StressFreeFileEditField.Value = strcat(postTpath,postTname);
        end

        % Value changed function: StressFreeFileEditField
        function StressFreeFileEditFieldValueChanged(app, event)
            value = app.StressFreeFileEditField.Value;
            [filepath,name,ext] = fileparts(value);
            assignin('base','postStem',filepath);
            assignin('base','postTreatmentFile',strcat(name,ext));
        end

        % Value changed function: PostTreatmentChannelField
        function PostTreatmentChannelFieldValueChanged(app, event)
            value = app.PostTreatmentChannelField.Value;
            assignin('base','postTreatmentChannel',value);
            if value == 0
                assignin('base','postTreatmentChannel',nan);
            end
        end

        % Button pushed function: PreTreatmentbutton
        function PreTreatmentbuttonButtonPushed(app, event)
             %get pre treatment file, extract folder path for stem
            %[preTname,preTpath] = uigetfile;
            preTpath = uigetdir;
            assignin('base','preStem',preTpath);            
            %assignin('base','preTreatmentFileName',preTname);
            
            %after setting, change the text field value
            %app.PreTreatmentField.Value = strcat(preTpath,preTname);
            app.PreTreatmentField.Value = preTpath;
            
            %turn to cell for consistency with existing code
            preTname = {preTpath};
            disp(preTname)
            assignin('base','preTreatmentFile',preTname);
        end

        % Value changed function: PreTreatmentField
        function PreTreatmentFieldValueChanged(app, event)
            value = app.PreTreatmentField.Value;
            [filepath,name,ext] = fileparts(value);
            assignin('base','preStem',filepath);
            assignin('base','preTreatmentFile',strcat(name,ext));
        end

        % Value changed function: PreTreatmentChannelField
        function PreTreatmentChannelFieldValueChanged(app, event)
            value = app.PreTreatmentChannelField.Value;
            assignin('base','preTreatmentChannel',value);
            if value == 0
                assignin('base','preTreatmentChannel',{nan});
            end
        end

        % Button pushed function: AnalysisOutputbutton
        function AnalysisOutputbuttonButtonPushed(app, event)
            outputPath = uigetdir;
            assignin('base','analysisStem',outputPath);
            app.AnalysisOutputField.Value = outputPath;
        end

        % Value changed function: AnalysisOutputField
        function AnalysisOutputFieldValueChanged(app, event)
            value = app.AnalysisOutputField.Value;
            assignin('base','analysisStem',value);
        end

        % Value changed function: AnalysisNameField
        function AnalysisNameFieldValueChanged(app, event)
            value = app.AnalysisNameField.Value;
            assignin('base','dataFileName',value)
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            value = app.XEditField.Value;
            assignin('base','size_x',value);
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            value = app.YEditField.Value;
            assignin('base','size_y',value);
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            value = app.ZEditField.Value;
            assignin('base','size_z',value);
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            assignin('base','beadthres',value);
        end

        % Value changed function: MinSizeEditField
        function MinSizeEditFieldValueChanged(app, event)
            value = app.MinSizeEditField.Value;
            assignin('base','beadmin',value);
        end

        % Value changed function: MaxSizeEditField
        function MaxSizeEditFieldValueChanged(app, event)
            value = app.MaxSizeEditField.Value;
            assignin('base','beadmax',value);
        end

        % Value changed function: WinSizeEditField
        function WinSizeEditFieldValueChanged(app, event)
            value = app.WinSizeEditField.Value;
            assignin('base','beadwin',value);
        end

        % Value changed function: IntensityEditField
        function IntensityEditFieldValueChanged(app, event)
            value = app.IntensityEditField.Value;
            assignin('base','beadint',value);
        end

        % Value changed function: MultiplierEditField
        function MultiplierEditFieldValueChanged(app, event)
            value = app.MultiplierEditField.Value;
            assignin('base','beadmult',value);
        end

        % Value changed function: knnFDEditField
        function knnFDEditFieldValueChanged(app, event)
            value = app.knnFDEditField.Value;
            assignin('base','tptknnFD',value);
        end

        % Value changed function: fmThresEditField
        function fmThresEditFieldValueChanged(app, event)
            value = app.fmThresEditField.Value;
            assignin('base','tptfmThres',value);
        end

        % Value changed function: knnFMEditField
        function knnFMEditFieldValueChanged(app, event)
            value = app.knnFMEditField.Value;
            assignin('base','tptknnFM',value);
        end

        % Value changed function: outlrThresEditField
        function outlrThresEditFieldValueChanged(app, event)
            value = app.outlrThresEditField.Value;
            assignin('base','tptoutThres',value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.isCZICheckBox = uicheckbox(app.UIFigure);
            app.isCZICheckBox.ValueChangedFcn = createCallbackFcn(app, @isCZICheckBoxValueChanged, true);
            app.isCZICheckBox.Text = 'is CZI?';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.PreTreatmentbutton = uibutton(app.UIFigure, 'push');
            app.PreTreatmentbutton.ButtonPushedFcn = createCallbackFcn(app, @PreTreatmentbuttonButtonPushed, true);
            app.PreTreatmentbutton.Text = 'Select';
            app.AnalysisOutputbutton = uibutton(app.UIFigure, 'push');
            app.AnalysisOutputbutton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisOutputbuttonButtonPushed, true);
            app.AnalysisOutputbutton.Text = 'Select';
            app.StressFreeFileEditField = uieditfield(app.UIFigure, 'text');
            app.StressFreeFileEditField.ValueChangedFcn = createCallbackFcn(app, @StressFreeFileEditFieldValueChanged, true);
            app.PreTreatmentField = uieditfield(app.UIFigure, 'text');
            app.PreTreatmentField.ValueChangedFcn = createCallbackFcn(app, @PreTreatmentFieldValueChanged, true);
            app.AnalysisOutputField = uieditfield(app.UIFigure, 'text');
            app.AnalysisOutputField.ValueChangedFcn = createCallbackFcn(app, @AnalysisOutputFieldValueChanged, true);
            app.PostTreatmentFileLabel = uilabel(app.UIFigure);
            app.PostTreatmentFileLabel.Text = 'Post Treatment File';
            app.PreTreatmentFolderLabel = uilabel(app.UIFigure);
            app.PreTreatmentFolderLabel.Text = 'Pre Treatment Folder';
            app.AnalysisOutputFolderLabel = uilabel(app.UIFigure);
            app.AnalysisOutputFolderLabel.Text = 'Analysis Output Folder';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.ChannelLabel = uilabel(app.UIFigure);
            app.ChannelLabel.Text = 'Channel';
            app.PostTreatmentChannelField = uieditfield(app.UIFigure, 'numeric');
            app.PostTreatmentChannelField.ValueChangedFcn = createCallbackFcn(app, @PostTreatmentChannelFieldValueChanged, true);
            app.PreTreatmentChannelField = uieditfield(app.UIFigure, 'numeric');
            app.PreTreatmentChannelField.ValueChangedFcn = createCallbackFcn(app, @PreTreatmentChannelFieldValueChanged, true);
            app.AnalysisNameLabel = uilabel(app.UIFigure);
            app.AnalysisNameLabel.Text = 'Analysis Name';
            app.AnalysisNameField = uieditfield(app.UIFigure, 'text');
            app.AnalysisNameField.ValueChangedFcn = createCallbackFcn(app, @AnalysisNameFieldValueChanged, true);
            app.VoxelSizeumLabel = uilabel(app.UIFigure);
            app.VoxelSizeumLabel.Text = 'Voxel Size (um)';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.BeadParametersLabel = uilabel(app.UIFigure);
            app.BeadParametersLabel.Text = 'Bead Parameters';
            app.ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdEditField.Limits = [0 1];
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.MinSizeEditFieldLabel = uilabel(app.UIFigure);
            app.MinSizeEditFieldLabel.Text = 'Min. Size';
            app.MinSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MinSizeEditFieldValueChanged, true);
            app.MaxSizeEditFieldLabel = uilabel(app.UIFigure);
            app.MaxSizeEditFieldLabel.Text = 'Max Size';
            app.MaxSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MaxSizeEditFieldValueChanged, true);
            app.WinSizeEditFieldLabel = uilabel(app.UIFigure);
            app.WinSizeEditFieldLabel.Text = 'Win. Size';
            app.WinSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.WinSizeEditField.ValueChangedFcn = createCallbackFcn(app, @WinSizeEditFieldValueChanged, true);
            app.IntensityEditFieldLabel = uilabel(app.UIFigure);
            app.IntensityEditFieldLabel.Text = 'Intensity';
            app.IntensityEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntensityEditField.ValueChangedFcn = createCallbackFcn(app, @IntensityEditFieldValueChanged, true);
            app.MultiplierEditFieldLabel = uilabel(app.UIFigure);
            app.MultiplierEditFieldLabel.Text = 'Multiplier';
            app.MultiplierEditField = uieditfield(app.UIFigure, 'numeric');
            app.MultiplierEditField.ValueChangedFcn = createCallbackFcn(app, @MultiplierEditFieldValueChanged, true);
            app.TPTParametersLabel = uilabel(app.UIFigure);
            app.TPTParametersLabel.Text = 'TPT Parameters';
            app.knnFDEditFieldLabel = uilabel(app.UIFigure);
            app.knnFDEditFieldLabel.Text = 'knnFD';
            app.knnFDEditField = uieditfield(app.UIFigure, 'numeric');
            app.knnFDEditField.ValueChangedFcn = createCallbackFcn(app, @knnFDEditFieldValueChanged, true);
            app.fmThresEditFieldLabel = uilabel(app.UIFigure);
            app.fmThresEditFieldLabel.Text = 'fmThres';
            app.fmThresEditField = uieditfield(app.UIFigure, 'numeric');
            app.fmThresEditField.ValueChangedFcn = createCallbackFcn(app, @fmThresEditFieldValueChanged, true);
            app.knnFMEditField_2Label = uilabel(app.UIFigure);
            app.knnFMEditField_2Label.Text = 'knnFM';
            app.knnFMEditField = uieditfield(app.UIFigure, 'numeric');
            app.knnFMEditField.ValueChangedFcn = createCallbackFcn(app, @knnFMEditFieldValueChanged, true);
            app.outlrThresEditFieldLabel = uilabel(app.UIFigure);
            app.outlrThresEditFieldLabel.Text = 'outlrThres';
            app.outlrThresEditField = uieditfield(app.UIFigure, 'numeric');
            app.outlrThresEditField.ValueChangedFcn = createCallbackFcn(app, @outlrThresEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = userInputGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end